classdef EEG_Profile_Creator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEG_Profile_CreatorUIFigure    matlab.ui.Figure
        FP2Lamp                        matlab.ui.control.Lamp
        FP2LampLabel                   matlab.ui.control.Label
        FilterNoisyChannelsCheckBox    matlab.ui.control.CheckBox
        WrittenbyArikNoimanOrenMordecyLabel  matlab.ui.control.Label
        EEGProfileCreatorLabel         matlab.ui.control.Label
        ChannelsLabel                  matlab.ui.control.Label
        CreateProfileButton            matlab.ui.control.Button
        FP2CheckBox                    matlab.ui.control.CheckBox
        T8Lamp                         matlab.ui.control.Lamp
        T8LampLabel                    matlab.ui.control.Label
        F4Lamp                         matlab.ui.control.Lamp
        F4LampLabel                    matlab.ui.control.Label
        F8Lamp                         matlab.ui.control.Lamp
        F8LampLabel                    matlab.ui.control.Label
        T7Lamp                         matlab.ui.control.Lamp
        T7LampLabel                    matlab.ui.control.Label
        F3Lamp                         matlab.ui.control.Lamp
        F3LampLabel                    matlab.ui.control.Label
        FP1Lamp                        matlab.ui.control.Lamp
        FP1LampLabel                   matlab.ui.control.Label
        F7Lamp                         matlab.ui.control.Lamp
        F7LampLabel                    matlab.ui.control.Label
        F4CheckBox                     matlab.ui.control.CheckBox
        F7CheckBox                     matlab.ui.control.CheckBox
        T7CheckBox                     matlab.ui.control.CheckBox
        F8CheckBox                     matlab.ui.control.CheckBox
        F3CheckBox                     matlab.ui.control.CheckBox
        T8CheckBox                     matlab.ui.control.CheckBox
        EditField                      matlab.ui.control.NumericEditField
        WindowsizeSlider               matlab.ui.control.Slider
        WindowsizeSliderLabel          matlab.ui.control.Label
        FP1CheckBox                    matlab.ui.control.CheckBox
        SeizureMeansecondsEditField    matlab.ui.control.NumericEditField
        SeizureMeansecondsEditFieldLabel  matlab.ui.control.Label
        SeizureDurationsecondsEditField  matlab.ui.control.NumericEditField
        SeizureDurationsecondsEditFieldLabel  matlab.ui.control.Label
        NumberofSeizureEditField       matlab.ui.control.NumericEditField
        NumberofSeizureEditFieldLabel  matlab.ui.control.Label
        TotalDurationhoursEditField_2  matlab.ui.control.NumericEditField
        TotalDurationhoursEditField_2Label  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        PatientDropDown                matlab.ui.control.DropDown
        PatientDropDownLabel           matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function modify_metadata(app,value)
            metatable = readtable('metadata.txt');
            index = strcmp(metatable.Var1,value);
            app.TotalDurationhoursEditField_2.Value = metatable.Var2(index);
            app.NumberofSeizureEditField.Value = metatable.Var3(index);
            app.SeizureDurationsecondsEditField.Value = metatable.Var4(index);
            app.SeizureMeansecondsEditField.Value = metatable.Var5(index);
        end
        
        function results = colorchange(~,value,event)
            if event == true
                results = value - 0.25;
            else
                results = value + 0.25;
            end
            
        end
        
        function [result,file_name] = EEG_Profile(app,patient,channels,window_size)
            d = uiprogressdlg(app.EEG_Profile_CreatorUIFigure,'Title','Creating EEG Profile','Message','1','Cancelable','on');
            d.Value = 0.25;
            d.Message = sprintf('Load files from %S:',patient);
            EEG = load_files(patient, channels);
            d.Value = 0.5;
            d.Message = sprintf('split_EEG dataset');
            [EEG_train,EEG_test] = split_EEG(EEG);
            if app.FilterNoisyChannelsCheckBox.Value
                d.Value = 0.65;
                d.Message = sprintf('Filter Noisy Channels');
                [EEG_train_no_noisy_ch,noisy_ch_index] = Remove_noisy(EEG_train, channels);
                ch_index = sum(noisy_ch_index)>(length(EEG_train)/2);
                channels_no_noisy = channels;
                channels_no_noisy(ch_index) = [];
            else
                EEG_train_no_noisy_ch = EEG_train;
                channels_no_noisy = channels;
            end
            d.Value = 0.75;
            d.Message = sprintf('Extract dataset');
            dataset_train = extract_dataset(EEG_train_no_noisy_ch ,channels_no_noisy,window_size);
            [dataset_test,len_test] = extract_dataset_test(EEG_test ,channels_no_noisy,window_size);
            d.Value = 0.75;
            d.Message = sprintf('Analyze dataset');
            [result] = analyze_datasets(dataset_train,dataset_test,len_test);
            d.Value = 1;
            d.Message = sprintf('Plot results');
            [file_name] = plot_result(result,patient,window_size,channels,channels_no_noisy);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            modify_metadata(app,'chb01');
            app.FP1Lamp.Color = 'white';
            app.F3Lamp.Color = 'white';
            app.T7Lamp.Color = 'white';
            app.F7Lamp.Color = 'white';
            app.FP2Lamp.Color = 'white';
            app.F4Lamp.Color = 'white';
            app.T8Lamp.Color = 'white';
            app.F8Lamp.Color = 'white';
        end

        % Value changed function: PatientDropDown, 
        % TotalDurationhoursEditField_2
        function PatientDropDownValueChanged(app, event)
            value = app.PatientDropDown.Value;
            modify_metadata(app,value);
        end

        % Value changed function: WindowsizeSlider
        function WindowsizeSliderValueChanged(app, event)
            value = floor(app.WindowsizeSlider.Value);
            if mod(value,2)
                value = value -1;
            end
            app.WindowsizeSlider.Value = value;
            app.EditField.Value = value;
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = floor(app.EditField.Value);
             if mod(value,2)
                value = value -1;
            end
            app.WindowsizeSlider.Value = value;
            app.EditField.Value = value;
        end

        % Value changed function: FP1CheckBox
        function FP1CheckBoxValueChanged(app, event)
            value = app.FP1CheckBox.Value;
            if value 
                app.FP1Lamp.Color = app.colorchange(app.FP1Lamp.Color,true);
            else
                app.FP1Lamp.Color = app.colorchange(app.FP1Lamp.Color,false);
            end
        end

        % Value changed function: F3CheckBox
        function F3CheckBoxValueChanged(app, event)
            value = app.F3CheckBox.Value;
            if value 
                app.F3Lamp.Color = app.colorchange(app.F3Lamp.Color,true);
            else
                app.F3Lamp.Color = app.colorchange(app.F3Lamp.Color,false);
            end
        end

        % Value changed function: T7CheckBox
        function T7CheckBoxValueChanged(app, event)
            value = app.T7CheckBox.Value;
            if value 
                app.T7Lamp.Color = app.colorchange(app.T7Lamp.Color,true);
            else
                app.T7Lamp.Color = app.colorchange(app.T7Lamp.Color,false);
            end
            
        end

        % Value changed function: F7CheckBox
        function F7CheckBoxValueChanged(app, event)
            value = app.F7CheckBox.Value;
            if value 
                app.F7Lamp.Color = app.colorchange(app.F7Lamp.Color,true);
            else
                app.F7Lamp.Color = app.colorchange(app.F7Lamp.Color,false);
            end
        end

        % Value changed function: T8CheckBox
        function T8CheckBoxValueChanged(app, event)
            value = app.T8CheckBox.Value;
            if value 
                app.T8Lamp.Color = app.colorchange(app.T8Lamp.Color,true);
            else
                app.T8Lamp.Color = app.colorchange(app.T8Lamp.Color,false);
            end
        end

        % Value changed function: F8CheckBox
        function F8CheckBoxValueChanged(app, event)
            value = app.F8CheckBox.Value;
            if value 
                app.F8Lamp.Color = app.colorchange(app.F8Lamp.Color,true);
            else
                app.F8Lamp.Color = app.colorchange(app.F8Lamp.Color,false);
            end
        end

        % Value changed function: FP2CheckBox
        function FP2CheckBoxValueChanged(app, event)
            value = app.FP2CheckBox.Value;
            if value 
                app.FP2Lamp.Color = app.colorchange(app.FP2Lamp.Color,true);
            else
                app.FP2Lamp.Color = app.colorchange(app.FP2Lamp.Color,false);
            end
        end

        % Value changed function: F4CheckBox
        function F4CheckBoxValueChanged(app, event)
            value = app.F4CheckBox.Value;
            if value 
                app.F4Lamp.Color = app.colorchange(app.F4Lamp.Color,true);
            else
                app.F4Lamp.Color = app.colorchange(app.F4Lamp.Color,false);
            end
        end

        % Button pushed function: CreateProfileButton
        function CreateProfileButtonPushed(app, event)
            channels = ["FP1","FP2","F3",...
                        "F4","T7","T8",...
                        "F7","F8"];
            channels_index = [app.FP1CheckBox.Value,app.F3CheckBox.Value, ...
                app.T7CheckBox.Value,app.F7CheckBox.Value,app.FP2CheckBox.Value,...
                app.F4CheckBox.Value,app.T8CheckBox.Value,app.F8CheckBox.Value];
            if ~any(channels_index)
                msgbox('Please select Channels','Error')
            else
                patient = app.PatientDropDown.Value;
                ch = channels(channels_index);
                window_size = app.EditField.Value;
                [~,file_name] = app.EEG_Profile(patient,ch,window_size);
                msgbox(sprintf('File save : %s',file_name),'Finish')   
                display_results_autoreflow(file_name)
            end

            function display_results_autoreflow(file_name)
            % Your code for displaying results based on the file_name
            disp(['Displaying results for file: ' file_name]);
            % Add your code here to display the results
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEG_Profile_CreatorUIFigure = uifigure('Visible', 'off');
            app.EEG_Profile_CreatorUIFigure.Name = 'EEG Profile Creator';
            app.PatientDropDownLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.PatientDropDownLabel.Text = 'Patient';
            app.PatientDropDown = uidropdown(app.EEG_Profile_CreatorUIFigure);
            app.PatientDropDown.Items = {'chb01', 'chb02', 'chb03', 'chb04', 'chb05', 'chb06', 'chb07', 'chb08', 'chb09', 'chb10'};
            app.PatientDropDown.ValueChangedFcn = createCallbackFcn(app, @PatientDropDownValueChanged, true);
            app.PatientDropDown.Value = 'chb01';
            app.Image = uiimage(app.EEG_Profile_CreatorUIFigure);
            app.Image.ImageSource = 'Enobio8.png';
            app.TotalDurationhoursEditField_2Label = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.TotalDurationhoursEditField_2Label.Text = 'Total Duration (hours)';
            app.TotalDurationhoursEditField_2 = uieditfield(app.EEG_Profile_CreatorUIFigure, 'numeric');
            app.TotalDurationhoursEditField_2.ValueChangedFcn = createCallbackFcn(app, @PatientDropDownValueChanged, true);
            app.NumberofSeizureEditFieldLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.NumberofSeizureEditFieldLabel.Text = 'Number of Seizure';
            app.NumberofSeizureEditField = uieditfield(app.EEG_Profile_CreatorUIFigure, 'numeric');
            app.SeizureDurationsecondsEditFieldLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.SeizureDurationsecondsEditFieldLabel.Text = 'Seizure Duration (seconds)';
            app.SeizureDurationsecondsEditField = uieditfield(app.EEG_Profile_CreatorUIFigure, 'numeric');
            app.SeizureMeansecondsEditFieldLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.SeizureMeansecondsEditFieldLabel.Text = 'Seizure Mean (seconds)';
            app.SeizureMeansecondsEditField = uieditfield(app.EEG_Profile_CreatorUIFigure, 'numeric');
            app.FP1CheckBox = uicheckbox(app.EEG_Profile_CreatorUIFigure);
            app.FP1CheckBox.ValueChangedFcn = createCallbackFcn(app, @FP1CheckBoxValueChanged, true);
            app.FP1CheckBox.Text = 'FP1';
            app.WindowsizeSliderLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.WindowsizeSliderLabel.Text = 'Window size';
            app.WindowsizeSlider = uislider(app.EEG_Profile_CreatorUIFigure);
            app.WindowsizeSlider.Limits = [256 4096];
            app.WindowsizeSlider.ValueChangedFcn = createCallbackFcn(app, @WindowsizeSliderValueChanged, true);
            app.WindowsizeSlider.Value = 256;
            app.EditField = uieditfield(app.EEG_Profile_CreatorUIFigure, 'numeric');
            app.EditField.Limits = [256 4096];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Value = 256;
            app.T8CheckBox = uicheckbox(app.EEG_Profile_CreatorUIFigure);
            app.T8CheckBox.ValueChangedFcn = createCallbackFcn(app, @T8CheckBoxValueChanged, true);
            app.T8CheckBox.Text = 'T8';
            app.F3CheckBox = uicheckbox(app.EEG_Profile_CreatorUIFigure);
            app.F3CheckBox.ValueChangedFcn = createCallbackFcn(app, @F3CheckBoxValueChanged, true);
            app.F3CheckBox.Text = 'F3';
            app.F8CheckBox = uicheckbox(app.EEG_Profile_CreatorUIFigure);
            app.F8CheckBox.ValueChangedFcn = createCallbackFcn(app, @F8CheckBoxValueChanged, true);
            app.F8CheckBox.Text = 'F8';
            app.T7CheckBox = uicheckbox(app.EEG_Profile_CreatorUIFigure);
            app.T7CheckBox.ValueChangedFcn = createCallbackFcn(app, @T7CheckBoxValueChanged, true);
            app.T7CheckBox.Text = 'T7';
            app.F7CheckBox = uicheckbox(app.EEG_Profile_CreatorUIFigure);
            app.F7CheckBox.ValueChangedFcn = createCallbackFcn(app, @F7CheckBoxValueChanged, true);
            app.F7CheckBox.Text = 'F7';
            app.F4CheckBox = uicheckbox(app.EEG_Profile_CreatorUIFigure);
            app.F4CheckBox.ValueChangedFcn = createCallbackFcn(app, @F4CheckBoxValueChanged, true);
            app.F4CheckBox.Text = 'F4';
            app.F7LampLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.F7LampLabel.Text = 'F7';
            app.F7Lamp = uilamp(app.EEG_Profile_CreatorUIFigure);
            app.FP1LampLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.FP1LampLabel.Text = 'FP1';
            app.FP1Lamp = uilamp(app.EEG_Profile_CreatorUIFigure);
            app.F3LampLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.F3LampLabel.Text = 'F3';
            app.F3Lamp = uilamp(app.EEG_Profile_CreatorUIFigure);
            app.T7LampLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.T7LampLabel.Text = 'T7';
            app.T7Lamp = uilamp(app.EEG_Profile_CreatorUIFigure);
            app.F8LampLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.F8LampLabel.Text = 'F8';
            app.F8Lamp = uilamp(app.EEG_Profile_CreatorUIFigure);
            app.F4LampLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.F4LampLabel.Text = 'F4';
            app.F4Lamp = uilamp(app.EEG_Profile_CreatorUIFigure);
            app.T8LampLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.T8LampLabel.Text = 'T8';
            app.T8Lamp = uilamp(app.EEG_Profile_CreatorUIFigure);
            app.FP2CheckBox = uicheckbox(app.EEG_Profile_CreatorUIFigure);
            app.FP2CheckBox.ValueChangedFcn = createCallbackFcn(app, @FP2CheckBoxValueChanged, true);
            app.FP2CheckBox.Text = 'FP2';
            app.CreateProfileButton = uibutton(app.EEG_Profile_CreatorUIFigure, 'push');
            app.CreateProfileButton.ButtonPushedFcn = createCallbackFcn(app, @CreateProfileButtonPushed, true);
            app.CreateProfileButton.Text = 'Create Profile';
            app.ChannelsLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.ChannelsLabel.Text = 'Channels:';
            app.EEGProfileCreatorLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.EEGProfileCreatorLabel.Text = {'EEG Profile Creator'; ''};
            app.WrittenbyArikNoimanOrenMordecyLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.WrittenbyArikNoimanOrenMordecyLabel.Text = 'Written by : Arik Noiman & Oren Mordecy';
            app.FilterNoisyChannelsCheckBox = uicheckbox(app.EEG_Profile_CreatorUIFigure);
            app.FilterNoisyChannelsCheckBox.Text = 'Filter Noisy Channels';
            app.FP2LampLabel = uilabel(app.EEG_Profile_CreatorUIFigure);
            app.FP2LampLabel.Text = 'FP2';
            app.FP2Lamp = uilamp(app.EEG_Profile_CreatorUIFigure);
            % Show the figure after all components are created
            app.EEG_Profile_CreatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG_Profile_Creator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEG_Profile_CreatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEG_Profile_CreatorUIFigure)
        end
    end
end